classdef EE441_CEP_Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DownloadFilteredImageButton     matlab.ui.control.Button
        NoisyImageMSEEditFieldLabel_6   matlab.ui.control.Label
        NoisyImageMSEEditFieldLabel_2   matlab.ui.control.Label
        NoisyImageMSEEditFieldLabel_5   matlab.ui.control.Label
        NoisyImageMSEEditFieldLabel_4   matlab.ui.control.Label
        NoisyImageMSEEditFieldLabel_3   matlab.ui.control.Label
        NoisyImageMSEEditField          matlab.ui.control.NumericEditField
        NoisyImageMSEEditFieldLabel     matlab.ui.control.Label
        FilteredImageMSEEditField       matlab.ui.control.NumericEditField
        FilteredImageMSEEditFieldLabel  matlab.ui.control.Label
        FilterIntensitySlider           matlab.ui.control.Slider
        FilterIntensitySliderLabel      matlab.ui.control.Label
        ChooseFilterTypeDropDown        matlab.ui.control.DropDown
        ChooseFilterTypeDropDownLabel   matlab.ui.control.Label
        NoiseIntensitySlider            matlab.ui.control.Slider
        NoiseIntensitySliderLabel       matlab.ui.control.Label
        ChooseNoiseTypeDropDown         matlab.ui.control.DropDown
        ChooseNoiseTypeDropDownLabel    matlab.ui.control.Label
        LoadFilteredImageButton         matlab.ui.control.Button
        LoadNoisyImageButton            matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile({'.png;.jpg;.jpeg;.bmp;*.gif'}, 'Select an Image');
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            imshow(img, 'Parent', app.UIAxes);
        end

        % Button pushed function: LoadNoisyImageButton
        function LoadNoisyImageButtonPushed(app, event)
            img = getimage(app.UIAxes);
    
            if isempty(img)
                uialert(app.UIFigure, 'No image loaded!', 'Error');
                return;
            end
    
            noiseType = app.ChooseNoiseTypeDropDown.Value;
            intensity = app.NoiseIntensitySlider.Value / 100;
    
            switch noiseType
                case 'Gaussian Noise'
                    noisyImg = imnoise(img, 'gaussian', 0, ((exp(intensity*4)-1)/(exp(4)-1)));
                case 'Salt and Pepper Noise'
                    noisyImg = imnoise(img, 'salt & pepper', ((exp(intensity*1.8)-1)/(exp(1.8)-1)));
                case 'Speckle Noise'
                    noisyImg = imnoise(img, 'speckle', ((exp(intensity*6)-1)/(exp(6)-1))*5);
                case 'No Noise'
                    noisyImg = imnoise(img, 'gaussian', 0, 0);
                otherwise
                    disp('Invalid noise type.');
                    return;
            end

            img = im2double(img);
            F2 = im2double(noisyImg);
            mse3 = mean((img(:) - F2(:)).^2);
            app.NoisyImageMSEEditField.Value = mse3*100;

            imshow(noisyImg, 'Parent', app.UIAxes2);
        end

        % Button pushed function: LoadFilteredImageButton
        function LoadFilteredImageButtonPushed(app, event)
             img1 = getimage(app.UIAxes2);
             img3 = getimage(app.UIAxes);
             noiseTypeA = app.ChooseNoiseTypeDropDown.Value;
             intensityA = app.NoiseIntensitySlider.Value / 100;
    
            if isempty(img1)
                uialert(app.UIFigure, 'No image loaded!', 'Error');
                return;
            end

             FilterType = app.ChooseFilterTypeDropDown.Value;
             FIntensity = app.FilterIntensitySlider.Value / 100;
             R = img1(:, :, 1);
             G = img1(:, :, 2);
             B = img1(:, :, 3);
             kernel_size = 1 + floor(FIntensity * 9);
             avgsize = 1 + floor(FIntensity * 5);

             switch FilterType
                 case 'Gaussian Filter'
                     denoise3_R = imgaussfilt(R, 1+(FIntensity*3))*(1+(FIntensity*0.25));
                     denoise3_G = imgaussfilt(G, 1+(FIntensity*3))*(1+(FIntensity*0.25));
                     denoise3_B = imgaussfilt(B, 1+(FIntensity*3))*(1+(FIntensity*0.25));
                     F = cat(3, denoise3_R, denoise3_G, denoise3_B);
                 case 'Median Filter'
                     denoise2_R = medfilt2(R, [kernel_size kernel_size])*(1+(FIntensity*0.1));
                     denoise2_G = medfilt2(G, [kernel_size kernel_size])*(1+(FIntensity*0.1));
                     denoise2_B = medfilt2(B, [kernel_size kernel_size])*(1+(FIntensity*0.1));
                     F = cat(3, denoise2_R, denoise2_G, denoise2_B);
                 case 'Average Filter'
                     denoise1_R = filter2(fspecial('average', avgsize), R)*(1+(FIntensity*0.2))/255;
                     denoise1_G = filter2(fspecial('average', avgsize), G)*(1+(FIntensity*0.2))/255;
                     denoise1_B = filter2(fspecial('average', avgsize), B)*(1+(FIntensity*0.2))/255;
                     F = cat(3, denoise1_R, denoise1_G, denoise1_B);
                 case 'Rank Order Filter'
                     denoise4_R = ordfilt2(R, floor(((floor(3 + 4 * FIntensity))^2 + 1) / 2), ones(floor(3 + (4 * FIntensity))))*(1+(FIntensity*0.1));
                     denoise4_G = ordfilt2(G, floor(((floor(3 + 4 * FIntensity))^2 + 1) / 2), ones(floor(3 + (4 * FIntensity))))*(1+(FIntensity*0.1));
                     denoise4_B = ordfilt2(B, floor(((floor(3 + 4 * FIntensity))^2 + 1) / 2), ones(floor(3 + (4 * FIntensity))))*(1+(FIntensity*0.1));
                     F = cat(3, denoise4_R, denoise4_G, denoise4_B);
                 case 'Wiener Filter'
                     denoise5_R = wiener2(R, [floor(5 + 4 * FIntensity), floor(5 + 4 * FIntensity)])*(1+(FIntensity*0.2));
                     denoise5_G = wiener2(G, [floor(5 + 4 * FIntensity), floor(5 + 4 * FIntensity)])*(1+(FIntensity*0.2));
                     denoise5_B = wiener2(B, [floor(5 + 4 * FIntensity), floor(5 + 4 * FIntensity)])*(1+(FIntensity*0.2));
                     F = cat(3, denoise5_R, denoise5_G, denoise5_B);
                 case 'Bilateral filter'
                     domainSize = floor(1 + 25 * FIntensity);  
                     rangeSigma = 0.1 + (4 * FIntensity);
                     denoise7_R = imbilatfilt(R, rangeSigma, domainSize) * (1 + (FIntensity * 0.1));
                     denoise7_G = imbilatfilt(G, rangeSigma, domainSize) * (1 + (FIntensity * 0.1));
                     denoise7_B = imbilatfilt(B, rangeSigma, domainSize) * (1 + (FIntensity * 0.1));
                     F = cat(3, denoise7_R, denoise7_G, denoise7_B);
                 case 'Wavelet Transform'
                     G_double1 = im2double(img1);
                     waveletType1 = 'sym4'; 
                     level = floor(1 + 5 * FIntensity);          
                     F = zeros(size(G_double1));
                     for channel = 1:3
                         G_channell = G_double1(:, :, channel);
                         if strcmp(noiseTypeA, 'speckle')
                             G_channell = log1p(G_channell); 
                         end
                         [c, s] = wavedec2(G_channell, level, waveletType1);
                         thr = intensityA * sqrt(2 * log(numel(G_channell)))* (1 - (FIntensity * 0.5)); 
                         denoised_coeffs = wthresh(c, 's', thr);
                         denoised_channel = waverec2(denoised_coeffs, s, waveletType1);
                         denoised_channel = min(max(denoised_channel, 0), 1);
                         if strcmp(noiseTypeA, 'speckle')
                             denoised_channel = expm1(denoised_channel);
                         end
                         F(:, :, channel) = min(max(denoised_channel, 0), 1);
                     end
                 case 'Median and Wiener Hybrid Filter'
                     denoise11_R = medfilt2(R, [kernel_size kernel_size]);
                     denoise11_G = medfilt2(G, [kernel_size kernel_size]);
                     denoise11_B = medfilt2(B, [kernel_size kernel_size]);
                     denoise9_R = wiener2(denoise11_R, [floor(5 + 4 * FIntensity), floor(5 + 4 * FIntensity)]) * (1 + (FIntensity * 0.15));
                     denoise9_G = wiener2(denoise11_G, [floor(5 + 4 * FIntensity), floor(5 + 4 * FIntensity)]) * (1 + (FIntensity * 0.15));
                     denoise9_B = wiener2(denoise11_B, [floor(5 + 4 * FIntensity), floor(5 + 4 * FIntensity)]) * (1 + (FIntensity * 0.15));
                     F = cat(3, denoise9_R, denoise9_G, denoise9_B);
                 otherwise
                    disp('Invalid noise type.');
                 return;
             end

             img3 = im2double(img3);
             F1 = im2double(F);
             mse1 = mean((img3(:) - F1(:)).^2);
             app.FilteredImageMSEEditField.Value = mse1*100;
             
             imshow(F, 'Parent', app.UIAxes3);
        end

        % Button pushed function: DownloadFilteredImageButton
        function DownloadFilteredImageButtonPushed(app, event)
            img = getimage(app.UIAxes3);
    
            if isempty(img)
                uialert(app.UIFigure, 'No image to download!', 'Error');
                return;
            end
    
            [file, path] = uiputfile('*.jpg', 'Save Image As');
            if isequal(file, 0)
                return;
            end
    
            savePath = fullfile(path, file);
    
            try
                imwrite(img, savePath);
                uialert(app.UIFigure, 'Image downloaded successfully!', 'Success');
            catch ME
                uialert(app.UIFigure, ['Failed to save image: ' ME.message], 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Noisy Image')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Filtered Image')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.Tag = 'Original Image';
            colormap(app.UIAxes, 'summer')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.LoadNoisyImageButton = uibutton(app.UIFigure, 'push');
            app.LoadNoisyImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNoisyImageButtonPushed, true);
            app.LoadNoisyImageButton.Text = 'Load Noisy Image';
            app.LoadFilteredImageButton = uibutton(app.UIFigure, 'push');
            app.LoadFilteredImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilteredImageButtonPushed, true);
            app.LoadFilteredImageButton.Text = 'Load Filtered Image';
            app.ChooseNoiseTypeDropDownLabel = uilabel(app.UIFigure);
            app.ChooseNoiseTypeDropDownLabel.Text = 'Choose Noise Type';
            app.ChooseNoiseTypeDropDown = uidropdown(app.UIFigure);
            app.ChooseNoiseTypeDropDown.Items = {'Gaussian Noise', 'Salt and Pepper Noise', 'Speckle Noise', 'No Noise'};
            app.ChooseNoiseTypeDropDown.Value = 'Gaussian Noise';
            app.NoiseIntensitySliderLabel = uilabel(app.UIFigure);
            app.NoiseIntensitySliderLabel.Text = {'Noise'; 'Intensity'};
            app.NoiseIntensitySlider = uislider(app.UIFigure);
            app.ChooseFilterTypeDropDownLabel = uilabel(app.UIFigure);
            app.ChooseFilterTypeDropDownLabel.Text = 'Choose Filter Type';
            app.ChooseFilterTypeDropDown = uidropdown(app.UIFigure);
            app.ChooseFilterTypeDropDown.Items = {'Gaussian Filter', 'Median Filter', 'Average Filter', 'Rank Order Filter', 'Wiener Filter', 'Bilateral filter', 'Wavelet Transform', 'Median and Wiener Hybrid Filter'};
            app.ChooseFilterTypeDropDown.Value = 'Gaussian Filter';
            app.FilterIntensitySliderLabel = uilabel(app.UIFigure);
            app.FilterIntensitySliderLabel.Text = {'Filter'; 'Intensity'};
            app.FilterIntensitySlider = uislider(app.UIFigure);
            app.FilteredImageMSEEditFieldLabel = uilabel(app.UIFigure);
            app.FilteredImageMSEEditFieldLabel.Text = {'Filtered Image'; '(MSE)'};
            app.FilteredImageMSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoisyImageMSEEditFieldLabel = uilabel(app.UIFigure);
            app.NoisyImageMSEEditFieldLabel.Text = {'Noisy Image'; '(MSE)'};
            app.NoisyImageMSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoisyImageMSEEditFieldLabel_3 = uilabel(app.UIFigure);
            app.NoisyImageMSEEditFieldLabel_3.Text = 'Image Denoising Filter Settings';
            app.NoisyImageMSEEditFieldLabel_4 = uilabel(app.UIFigure);
            app.NoisyImageMSEEditFieldLabel_4.Text = 'Image Noise Parameter Settings';
            app.NoisyImageMSEEditFieldLabel_5 = uilabel(app.UIFigure);
            app.NoisyImageMSEEditFieldLabel_5.Text = 'Image Comparison';
            app.NoisyImageMSEEditFieldLabel_2 = uilabel(app.UIFigure);
            app.NoisyImageMSEEditFieldLabel_2.Text = 'Image Noise Analyses';
            app.NoisyImageMSEEditFieldLabel_6 = uilabel(app.UIFigure);
            app.NoisyImageMSEEditFieldLabel_6.Text = 'Image Denoising App';
            app.DownloadFilteredImageButton = uibutton(app.UIFigure, 'push');
            app.DownloadFilteredImageButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadFilteredImageButtonPushed, true);
            app.DownloadFilteredImageButton.Text = 'Download Filtered Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EE441_CEP_Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end